classdef appR17mlapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        ManualTab                   matlab.ui.container.Tab
        SeleccioneClaseButtonGroup  matlab.ui.container.ButtonGroup
        Button                      matlab.ui.control.RadioButton
        Button_2                    matlab.ui.control.RadioButton
        Button_3                    matlab.ui.control.RadioButton
        Button_4                    matlab.ui.control.RadioButton
        Button_5                    matlab.ui.control.RadioButton
        BCIUVGLabel                 matlab.ui.control.Label
        RodrigoRaldaGLabel          matlab.ui.control.Label
        AutomticoTab                matlab.ui.container.Tab
        ClaseActualEditFieldLabel   matlab.ui.control.Label
        ClaseActualEditField        matlab.ui.control.NumericEditField
        figFpz                      matlab.ui.control.UIAxes
        figPz                       matlab.ui.control.UIAxes
        figEog                      matlab.ui.control.UIAxes
        figEmg                      matlab.ui.control.UIAxes
        MAVEditFieldLabel           matlab.ui.control.Label
        MAVEditField                matlab.ui.control.NumericEditField
        ZCEditFieldLabel            matlab.ui.control.Label
        ZCEditField                 matlab.ui.control.NumericEditField
        MMDEditFieldLabel           matlab.ui.control.Label
        MMDEditField                matlab.ui.control.NumericEditField
        BPEditFieldLabel            matlab.ui.control.Label
        BPEditField                 matlab.ui.control.NumericEditField
        KURTEditFieldLabel          matlab.ui.control.Label
        KURTEditField               matlab.ui.control.NumericEditField
        FPZLabel                    matlab.ui.control.Label
        PZLabel                     matlab.ui.control.Label
        MAVEditField_2Label         matlab.ui.control.Label
        MAVEditField_2              matlab.ui.control.NumericEditField
        ZCEditField_2Label          matlab.ui.control.Label
        ZCEditField_2               matlab.ui.control.NumericEditField
        MMDEditField_2Label         matlab.ui.control.Label
        MMDEditField_2              matlab.ui.control.NumericEditField
        BPEditField_2Label          matlab.ui.control.Label
        BPEditField_2               matlab.ui.control.NumericEditField
        KURTEditField_2Label        matlab.ui.control.Label
        KURTEditField_2             matlab.ui.control.NumericEditField
        EOGLabel                    matlab.ui.control.Label
        MAVEditField_3Label         matlab.ui.control.Label
        MAVEditField_3              matlab.ui.control.NumericEditField
        ZCEditField_3Label          matlab.ui.control.Label
        ZCEditField_3               matlab.ui.control.NumericEditField
        MMDEditField_3Label         matlab.ui.control.Label
        MMDEditField_3              matlab.ui.control.NumericEditField
        BPEditField_3Label          matlab.ui.control.Label
        BPEditField_3               matlab.ui.control.NumericEditField
        KURTEditField_3Label        matlab.ui.control.Label
        KURTEditField_3             matlab.ui.control.NumericEditField
        EMGLabel                    matlab.ui.control.Label
        MAVEditField_4Label         matlab.ui.control.Label
        MAVEditField_4              matlab.ui.control.NumericEditField
        IniciargrabacinSwitchLabel  matlab.ui.control.Label
        IniciargrabacinSwitch       matlab.ui.control.Switch
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: SeleccioneClaseButtonGroup
        function SeleccioneClaseButtonGroupSelectionChanged(app, event)
            selectedButton = app.SeleccioneClaseButtonGroup.SelectedObject;
            value = app.IniciargrabacinSwitch.Value;
            load('robotData.mat')
            
            if strcmp( value,'Off') 

                if selectedButton == app.Button
                    r17.plot(Qtot{1,1})
                    
                elseif selectedButton == app.Button_2
                    r17.plot(Qtot{1,2})

                elseif selectedButton == app.Button_3
                    r17.plot(Qtot{1,3})

                elseif selectedButton == app.Button_4
                    r17.plot(Qtot{1,4})

                else
                    r17.plot(Qtot{1,5})   

                end
            end
            
            
        end

        % Value changed function: IniciargrabacinSwitch
        function IniciargrabacinSwitchValueChanged(app, event)
            value = app.IniciargrabacinSwitch.Value;
            load('data_1')
            load('trainedModelSVM3.mat')
            load('robotData.mat')
            largoBuffer = 1;
            Epocas = 3000;
            L = largoBuffer*Epocas;
            data = zeros(4,L);
            etiquetasSVM = zeros(757,1);
            contEpocs = 1;
            t=linspace(1, 30, L);
                
            flag=0;
            
            i=0;
            
            if strcmp( value,'On')
                flag =1;
                
                h1 = plot(app.figFpz, t, zeros(1,L))
                h2 = plot(app.figPz, t, zeros(1,L))
                h3 = plot(app.figEog, t, zeros(1,L))
                h4 = plot(app.figEmg, t, zeros(1,L))
                
                ylim(app.figFpz, [min(fpz) max(fpz)]);
                ylim(app.figPz, [min(pz) max(pz)]);
                ylim(app.figEog, [min(eog) max(eog)]);
                ylim(app.figEmg, [min(emg) max(emg)]);
                while flag==1
                    value = app.IniciargrabacinSwitch.Value;
                    if strcmp( value,'Off')
                        break
                    end
                    
                    i = i+1;
                    n = mod(i,Epocas);
                    
                    if n == 0
                        n = Epocas;
                    end
                    
                    data(:,n)=[fpz(i);
                                pz(i);
                                eog(i);
                                emg(i)];
                            
                    %*****************Figuras
               
                    h1.YData(n) = fpz(i);
                    h2.YData(n) = pz(i);
                    h3.YData(n) = eog(i);
                    h4.YData(n) = emg(i);
                    drawnow limitrate
                    
                    if n == Epocas
                       % Calcular metricas
                        media = [mean(abs(data(1,:))), metricas(data(1,:),0,0), MMD(data(1,:)), bandpower(data(1,:)), kurtosis(data(1,:)),...
                                mean(abs(data(2,:))), metricas(data(2,:),0,0), MMD(data(2,:)), bandpower(data(2,:)), kurtosis(data(2,:)),...
                                mean(abs(data(3,:))), metricas(data(3,:),0,0), MMD(data(1,:)), bandpower(data(3,:)), kurtosis(data(3,:)),...
                                mean(abs(data(4,:)))];
                        etiquetasSVM(contEpocs)= trainedModelSVM3.predictFcn(media);
                        app.ClaseActualEditField.Value = etiquetasSVM(contEpocs);
                        %etiquetasSVM(contEpocs)
                        app.MAVEditField.Value = media(1,1);
                        app.ZCEditField.Value = media(1,2);
                        app.MMDEditField.Value = media(1,3);
                        app.BPEditField.Value = media(1,4);
                        app.KURTEditField.Value = media(1,5);
                        
                        app.MAVEditField_2.Value = media(1,6);
                        app.ZCEditField_2.Value = media(1,7);
                        app.MMDEditField_2.Value = media(1,8);
                        app.BPEditField_2.Value = media(1,9);
                        app.KURTEditField_2.Value = media(1,10);
                        
                        app.MAVEditField_3.Value = media(1,11);
                        app.ZCEditField_3.Value = media(1,12);
                        app.MMDEditField_3.Value = media(1,13);
                        app.BPEditField_3.Value = media(1,14);
                        app.KURTEditField_3.Value = media(1,15);
                        
                        app.MAVEditField_4.Value = media(1,16);
                        
                        %Robot
                        r17.plot(Qtot{1,etiquetasSVM(contEpocs)})
   
                        contEpocs = contEpocs+1;
                    end
                    
                end
                   
            else
                flag = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ManualTab = uitab(app.TabGroup);
            app.ManualTab.Title = 'Manual';
            app.SeleccioneClaseButtonGroup = uibuttongroup(app.ManualTab);
            app.SeleccioneClaseButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SeleccioneClaseButtonGroupSelectionChanged, true);
            app.SeleccioneClaseButtonGroup.Title = 'Seleccione Clase';
            app.Button = uiradiobutton(app.SeleccioneClaseButtonGroup);
            app.Button.Text = '1';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.SeleccioneClaseButtonGroup);
            app.Button_2.Text = '2';
            app.Button_3 = uiradiobutton(app.SeleccioneClaseButtonGroup);
            app.Button_3.Text = '3';
            app.Button_4 = uiradiobutton(app.SeleccioneClaseButtonGroup);
            app.Button_4.Text = '4';
            app.Button_5 = uiradiobutton(app.SeleccioneClaseButtonGroup);
            app.Button_5.Text = '5';
            app.BCIUVGLabel = uilabel(app.ManualTab);
            app.BCIUVGLabel.Text = 'BCI - UVG';
            app.RodrigoRaldaGLabel = uilabel(app.ManualTab);
            app.RodrigoRaldaGLabel.Text = 'Rodrigo Ralda G.';
            app.AutomticoTab = uitab(app.TabGroup);
            app.AutomticoTab.Title = 'Automático';
            app.ClaseActualEditFieldLabel = uilabel(app.AutomticoTab);
            app.ClaseActualEditFieldLabel.Text = 'Clase Actual';
            app.ClaseActualEditField = uieditfield(app.AutomticoTab, 'numeric');
            app.figFpz = uiaxes(app.AutomticoTab);
            title(app.figFpz, 'Fpz')
            xlabel(app.figFpz, 't')
            ylabel(app.figFpz, 'uV')
            app.figPz = uiaxes(app.AutomticoTab);
            title(app.figPz, 'Pz')
            xlabel(app.figPz, 't')
            ylabel(app.figPz, 'uV')
            app.figEog = uiaxes(app.AutomticoTab);
            title(app.figEog, 'EOG')
            xlabel(app.figEog, 't')
            ylabel(app.figEog, 'uV')
            app.figEmg = uiaxes(app.AutomticoTab);
            title(app.figEmg, 'EMG')
            xlabel(app.figEmg, 't')
            ylabel(app.figEmg, 'uV')
            app.MAVEditFieldLabel = uilabel(app.AutomticoTab);
            app.MAVEditFieldLabel.Text = 'MAV';
            app.MAVEditField = uieditfield(app.AutomticoTab, 'numeric');
            app.ZCEditFieldLabel = uilabel(app.AutomticoTab);
            app.ZCEditFieldLabel.Text = 'ZC';
            app.ZCEditField = uieditfield(app.AutomticoTab, 'numeric');
            app.MMDEditFieldLabel = uilabel(app.AutomticoTab);
            app.MMDEditFieldLabel.Text = 'MMD';
            app.MMDEditField = uieditfield(app.AutomticoTab, 'numeric');
            app.BPEditFieldLabel = uilabel(app.AutomticoTab);
            app.BPEditFieldLabel.Text = 'BP';
            app.BPEditField = uieditfield(app.AutomticoTab, 'numeric');
            app.KURTEditFieldLabel = uilabel(app.AutomticoTab);
            app.KURTEditFieldLabel.Text = 'KURT';
            app.KURTEditField = uieditfield(app.AutomticoTab, 'numeric');
            app.FPZLabel = uilabel(app.AutomticoTab);
            app.FPZLabel.Text = 'FPZ';
            app.PZLabel = uilabel(app.AutomticoTab);
            app.PZLabel.Text = 'PZ';
            app.MAVEditField_2Label = uilabel(app.AutomticoTab);
            app.MAVEditField_2Label.Text = 'MAV';
            app.MAVEditField_2 = uieditfield(app.AutomticoTab, 'numeric');
            app.ZCEditField_2Label = uilabel(app.AutomticoTab);
            app.ZCEditField_2Label.Text = 'ZC';
            app.ZCEditField_2 = uieditfield(app.AutomticoTab, 'numeric');
            app.MMDEditField_2Label = uilabel(app.AutomticoTab);
            app.MMDEditField_2Label.Text = 'MMD';
            app.MMDEditField_2 = uieditfield(app.AutomticoTab, 'numeric');
            app.BPEditField_2Label = uilabel(app.AutomticoTab);
            app.BPEditField_2Label.Text = 'BP';
            app.BPEditField_2 = uieditfield(app.AutomticoTab, 'numeric');
            app.KURTEditField_2Label = uilabel(app.AutomticoTab);
            app.KURTEditField_2Label.Text = 'KURT';
            app.KURTEditField_2 = uieditfield(app.AutomticoTab, 'numeric');
            app.EOGLabel = uilabel(app.AutomticoTab);
            app.EOGLabel.Text = 'EOG';
            app.MAVEditField_3Label = uilabel(app.AutomticoTab);
            app.MAVEditField_3Label.Text = 'MAV';
            app.MAVEditField_3 = uieditfield(app.AutomticoTab, 'numeric');
            app.ZCEditField_3Label = uilabel(app.AutomticoTab);
            app.ZCEditField_3Label.Text = 'ZC';
            app.ZCEditField_3 = uieditfield(app.AutomticoTab, 'numeric');
            app.MMDEditField_3Label = uilabel(app.AutomticoTab);
            app.MMDEditField_3Label.Text = 'MMD';
            app.MMDEditField_3 = uieditfield(app.AutomticoTab, 'numeric');
            app.BPEditField_3Label = uilabel(app.AutomticoTab);
            app.BPEditField_3Label.Text = 'BP';
            app.BPEditField_3 = uieditfield(app.AutomticoTab, 'numeric');
            app.KURTEditField_3Label = uilabel(app.AutomticoTab);
            app.KURTEditField_3Label.Text = 'KURT';
            app.KURTEditField_3 = uieditfield(app.AutomticoTab, 'numeric');
            app.EMGLabel = uilabel(app.AutomticoTab);
            app.EMGLabel.Text = 'EMG';
            app.MAVEditField_4Label = uilabel(app.AutomticoTab);
            app.MAVEditField_4Label.Text = 'MAV';
            app.MAVEditField_4 = uieditfield(app.AutomticoTab, 'numeric');
            app.IniciargrabacinSwitchLabel = uilabel(app.UIFigure);
            app.IniciargrabacinSwitchLabel.Text = 'Iniciar grabación';
            app.IniciargrabacinSwitch = uiswitch(app.UIFigure, 'slider');
            app.IniciargrabacinSwitch.ValueChangedFcn = createCallbackFcn(app, @IniciargrabacinSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appR17mlapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end